classdef ClientGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        StartButton    matlab.ui.control.Button
        YourNameLabel  matlab.ui.control.Label
        NameInput      matlab.ui.control.EditField
    end


    properties (Access = private)
        player_name
        game_window % Description
        Server
        
    end

    methods (Access = public)
    
        function results = name(app)
            results = app.player_name;
        end

        function results = server(app)
            results = app.Server;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ser)
            %ser = load("server.mat");
            app.Server = ser;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            success = app.Server.addAccount(app.NameInput.Value);     
            if (success)
                app.player_name = app.NameInput.Value;
                is_enter = app.Server.controller().enterPlayer(app.player_name);
                if (is_enter)
                    app.StartButton.Enable = 'off';
                    app.game_window = GameWindowGUI(app);
                else
                    app.Server.deleteAccount(app.NameInput.Value);   
                    msgbox('Current Room Is Full, please wait or choose another room');
                end
                
            else
                msgbox('Username Already Exist!');
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.game_window)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.YourNameLabel = uilabel(app.UIFigure);
            app.YourNameLabel.Text = 'Your Name';
            app.NameInput = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClientGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end